classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MainMenuButton       matlab.ui.control.Button
        MainMenuPanel        matlab.ui.container.Panel
        XORPanel             matlab.ui.container.Panel
        XORKeyEditField      matlab.ui.control.EditField
        XORKeyLabel          matlab.ui.control.Label
        CaesarPanel          matlab.ui.container.Panel
        ShiftEditField       matlab.ui.control.NumericEditField
        ShiftEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        UI struct                     % dynamic UI handles per panel
    
        currentText char = ''
        currentImage = []
        outputText char = ''
        outputImage = []
        currentKey char = ''
        currentModule char = ''

        isInputImage logical = false;   % default = text mode
    end

    
    methods (Access = public)
        %% Panel switching & utils
        function switchPanel(app, panelName)
            % Hide all panel-type properties and show the selected one
            props = properties(app);
            for i = 1:numel(props)
                n = props{i};
                try
                    if isa(app.(n),'matlab.ui.container.Panel')
                        app.(n).Visible = 'off';
                    end
                catch
                end
            end
            % show requested panel
            app.(panelName).Visible = 'on';
            app.currentModule = panelName;
            % reset module state when switching in
            resetModule(app,panelName);
            updateStatus(app, "Ready");
        end

        %% UI creation helpers (call at startup)
        function addPanels(app)
            

            uilabel(app.MainMenuPanel, 'Text','Select module:', 'Position',[20 340 200 22]);
            uibutton(app.MainMenuPanel, 'Text','Caesar Cipher', 'Position',[20 300 160 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'CaesarPanel'));
            uibutton(app.MainMenuPanel, 'Text','XOR (Text & Image)', 'Position',[200 300 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'XORPanel'));
        end

        %% Image load/save wrappers used by generated UI buttons
        function Btn_LoadImagePushed(app, event, panelName)
            loadImageFile(app, panelName);
        end

        %%
        function Btn_SaveImagePushed(app, event, panelName)
            saveImageFile(app, panelName);
        end

        %% Reset button wrapper
        function Btn_ResetPushed(app, event, panelName)
            resetModule(app, panelName);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add project subfolders to path so handlers/crypt/io/preview/components are visible
            root = fileparts(mfilename('fullpath'));
            addpath(fullfile(root,'components'));
            addpath(fullfile(root,'crypt'));
            addpath(fullfile(root,'handlers'));
            addpath(fullfile(root,'io'));
            addpath(fullfile(root,'io','encoding'));
        
            % initialize namespace
            app.UI = struct();


            % Create static panels and main menu
            addPanels(app);

            % create reusable UI inside panels
            addReusableIOComponents(app,'CaesarPanel', false); % text only
            addReusableIOComponents(app,'XORPanel', true);     % text and image

            % show Main Menu first
            switchPanel(app,'MainMenuPanel');

        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            switchPanel(app, 'MainMenuPanel')
        end

        % Value changed function: XORKeyEditField
        function XORKeyEditFieldValueChanged(app, event)
            value = app.XORKeyEditField.Value;
            app.currentKey = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CaesarPanel = uipanel(app.UIFigure);
            app.CaesarPanel.Title = 'Caesar';
            app.CaesarPanel.Visible = 'off';
            app.ShiftEditFieldLabel = uilabel(app.CaesarPanel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.CaesarPanel, 'numeric');
            app.ShiftEditField.Limits = [1 25];
            app.ShiftEditField.ValueDisplayFormat = '%.0f';
            app.ShiftEditField.Value = 3;
            app.XORPanel = uipanel(app.UIFigure);
            app.XORPanel.Title = 'XOR';
            app.XORPanel.Visible = 'off';
            app.XORKeyLabel = uilabel(app.XORPanel);
            app.XORKeyLabel.Text = 'XOR Key';
            app.XORKeyEditField = uieditfield(app.XORPanel, 'text');
            app.XORKeyEditField.ValueChangedFcn = createCallbackFcn(app, @XORKeyEditFieldValueChanged, true);
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.MainMenuPanel.Visible = 'off';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end